classdef Dynamic_Scaling_GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure     matlab.ui.Figure
        SliderLabel  matlab.ui.control.Label
        Slider       matlab.ui.control.Slider
        UIAxes       matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        lander_height %
        lander_width %
        vehicle
        scale
        Sarm
        vehicle_body
        lander_body
        lander_legL
        lander_legR
        lander_legM
        base
        link3
        link2
        arm
        scale_factor
    end
    
    methods (Access = private)
        
        function F = scaling(app, scale_factor,vehicle,arm)
            %lander parameters 
            app.lander_height = 3*scale_factor;
            app.lander_width = 2*scale_factor;
            
            % Vehicle Structure 
            vehicle.length = 1.75*scale_factor;
            vehicle.wheelRadius = 0.4*scale_factor;
            vehicle.height = (1.2*scale_factor)-vehicle.wheelRadius;
            
            % Creates the structure for the robotic arm parameter
            app.Sarm.length = arm.length*scale_factor;
            app.Sarm.width = arm.width*scale_factor;
            app.Sarm.base = arm.base*scale_factor;
            app.Sarm.secondlink = arm.secondlink*scale_factor;
            app.Sarm.endeffector = arm.endeffector*scale_factor;
            % Sarm.gripper = arm.gripper*scale_factor;
            
            % Base linkage of the arm position and orientation
            app.Sarm.x_position = 1.75*scale_factor;
            app.Sarm.y_position = (vehicle.height+vehicle.wheelRadius); 
            app.Sarm.angle = 25*pi/180;
            
            % Second linkage of the arm position and orientation
            app.Sarm.angle_L2 = 0*pi/180;
            app.Sarm.x_position2 = app.Sarm.x_position+app.Sarm.base*cos(app.Sarm.angle);
            app.Sarm.y_position2 = app.Sarm.y_position+app.Sarm.base*sin(app.Sarm.angle);
            
            % End Effector linkage of the arm position and orientation
            app.Sarm.angle_L3 = 200*pi/180;
            app.Sarm.x_position3 = app.Sarm.x_position2+app.Sarm.secondlink*cos(app.Sarm.angle_L2);
            app.Sarm.y_position3 = app.Sarm.y_position2+app.Sarm.secondlink*sin(app.Sarm.angle_L2);
            
            % % Simplified Model of Gripper
            % Sarm.angle_L4 = 270*pi/180;
            % Sarm.x_position4 = Sarm.x_position3+Sarm.endeffector*sin(Sarm.angle_L3);
            % Sarm.y_position4 = Sarm.y_position3+Sarm.endeffector*cos(Sarm.angle_L3)
            
            % Sets figure settings 
            fig = figure(); %sets the location and size of drawable area for the new figure 
            ax = uiaxes('XLim',[-1 10],'YLim',[-1 10]);
            
            % Parent handler of the transform
            hgTx = hgtransform('Parent',ax);
            hgTxB = hgtransform('Parent',ax);
            hgTxS = hgtransform('Parent',ax);
            hgTxE = hgtransform('Parent',ax);
            % hgTxG = hgtransform('Parent',ax);
            
            % rectangle is in the form [x y w h] 
            
            % Creates the vehicle body object 
            app.vehicle_body = rectangle('Parent', hgTx, 'Position',[(app.lander_width/2)+(0.25*scale_factor) vehicle.wheelRadius vehicle.length vehicle.height], 'FaceColor','red');
            
            % Creates the lander body object 
            app.lander_body = rectangle('Parent', hgTx, 'Position',[-app.lander_width/2 scale_factor app.lander_width app.lander_height-(1*scale_factor)], 'EdgeColor','black');
            app.lander_legL = rectangle('Parent', hgTx, 'Position',[-app.lander_width/2 0 app.lander_width*0.1 scale_factor], 'FaceColor','black');
            app.lander_legR = rectangle('Parent', hgTx, 'Position',[(app.lander_width/2)-app.lander_width*0.1 0 app.lander_width*0.1 scale_factor], 'FaceColor','black');
            app.lander_legM = rectangle('Parent', hgTx, 'Position',[0-((app.lander_width*0.1)/2) 0 app.lander_width*0.1 scale_factor], 'FaceColor','black');
            
            % Creates objects to simulate the arm
            app.base = rectangle('Parent', hgTxB, 'Position',[0 0 app.Sarm.base app.Sarm.width], 'EdgeColor','red');
            app.link2 = rectangle('Parent', hgTxS, 'Position',[0 0 app.Sarm.secondlink app.Sarm.width], 'EdgeColor','red');
            app.link3 = rectangle('Parent', hgTxE, 'Position',[0 0 app.Sarm.endeffector app.Sarm.width], 'EdgeColor','red');
            % gripper = rectangle('Parent', hgTxG, 'Position',[0 0 Sarm.gripper Sarm.width], 'FaceColor','yellow');
            
            % Rotation of Robot Linkages
            rotationB = linspace(128,108,10)*(pi/180);
            rotationS = linspace(82,34,10)*(pi/180);
            rotationEE = linspace(-9,-3,10)*(pi/180);
            
            y = -1:0.01:10; % for the dashed line indicating center of the lander 
            x = 0*ones(1,length(y)); % enough zeros for vectors to be equal 
            
            x2 = -1:0.01:10; % for the dashed line indicating center of the lander 
            y2 = 1.5*ones(1,length(y)); % enough zeros for vectors to be equal 
            
            % Text strings to show the lengths on the plot 
            base_length = ['Length of Base: ',num2str(app.Sarm.base), 'm'];
            second_length = ['Length of Link2: ',num2str(app.Sarm.secondlink), 'm'];
            endeff_length = ['Length of End Effector: ',num2str(app.Sarm.endeffector), 'm'];
            title_str = ['Robotic Arm Simulation at Scale ',num2str(round(scale_factor,2))];
            % gripper_length = ['Length of Gripper: ',num2str(arm.gripper), 'm'];
            
            % v = VideoWriter('scaled1.mp4'); % create video object
            % v.Quality = 50; % sets the video quality 
            % open(v); % open the video object 
        
            for i = 1:length(rotationS)
                app.Sarm.angle = rotationB(i); 
                app.Sarm.angle_L2 =  rotationS(i); % rotating the secondary linkage
                app.Sarm.angle_L3 = rotationEE(i); % rotating the end effector 
                
                app.Sarm.x_position2 = app.Sarm.x_position+app.Sarm.base*cos(app.Sarm.angle); % secondary linkage position updates based on base link
                app.Sarm.y_position2 = app.Sarm.y_position+app.Sarm.base*sin(app.Sarm.angle);
            
                app.Sarm.x_position3 = app.Sarm.x_position2+app.Sarm.secondlink*cos(app.Sarm.angle_L2); % EE updates position based on secondary linkage 
                app.Sarm.y_position3 = app.Sarm.y_position2+app.Sarm.secondlink*sin(app.Sarm.angle_L2);
                
            %     Sarm.x_position4 = Sarm.x_position3+Sarm.endeffector*cos(Sarm.angle_L3);
            %     Sarm.y_position4 = Sarm.y_position3+Sarm.endeffector*sin(Sarm.angle_L3);
            
                drawrobot(app, app.Sarm,hgTxB,hgTxS,hgTxE); %calls the function to transform the object location 
                
                % makes the plots pretty 
                hold on 
                uixlabel('X (m)')
                uiylabel('Y (m)')
                uititle(title_str,'FontSize',15)
                text(7,7.5,base_length); 
                text(7,7,second_length);
                text(7,6.5,endeff_length); 
                uiplot(x,y,'k--')
                uiplot(x2,y2,'k--')
                grid minor 
                hold off 
                % the pause is so the loop doesn't fly through the rotation array 
                pause(1.5)
                F(i) = getframe(fig);
                imshow(fig, 'Parent', app.UIAxes)
            end
        end
        
        function drawrobot(~,arm,hgTxB,hgTxS,hgTxE)
            % This function takes the position input and sends the robot to that
            % location/trajectory. 
            hgTxB.Matrix = makehgtform('translate', [arm.x_position arm.y_position 0], 'zrotate', arm.angle);
            hgTxS.Matrix = makehgtform('translate', [arm.x_position2 arm.y_position2 0], 'zrotate', arm.angle_L2);
            hgTxE.Matrix = makehgtform('translate', [arm.x_position3 arm.y_position3 0], 'zrotate', arm.angle_L3);
            % hgTxG.Matrix = makehgtform('translate', [arm.x_position4 arm.y_position4 0], 'zrotate', arm.angle_L4,'yrotate',270); 
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %lander parameters 
            app.lander_height = 3; 
            app.lander_width = 2; 
            
            app.scale_factor = 1;
            % Vehicle Structure 
            app.vehicle.length = 1.75;
            app.vehicle.wheelRadius = 0.4;
            app.vehicle.height = 1.2-app.vehicle.wheelRadius;
            
            % Creates the structure for the robotic arm parameter
            app.arm.length = (app.lander_height+0.5);
            app.arm.width = 0.25;
            app.arm.base = app.arm.length*0.35;
            app.arm.secondlink = app.arm.length*0.35;
            app.arm.endeffector = app.arm.length*0.2;
            % arm.gripper = arm.length*0.4;
            
            % Volumes and Masses 
            % density of titanium is 4.056 g/cm^3
            % density of graphite epoxy is 2.26 g/cm^3
            p = 1000; %4.056*(1/1000)*(100^3); % density 
            g = 9.81; % m/s^2, lunar gravity 
            % Volume*p = mass 
            app.arm.mass1 = app.arm.base*pi*((app.arm.width/4)^2)*p;
            app.arm.mass2 = app.arm.secondlink*pi*((app.arm.width/4)^2)*p;
            app.arm.mass3 = app.arm.endeffector*pi*((app.arm.width/4)^2)*p;
            
            % scaled mass from LeWan Soul =    2.1484e+03 kg 
            % Mass * gravity = weight; 
            app.arm.weight1 = app.arm.mass1*g;
            app.arm.weight2 = app.arm.mass2*g;
            app.arm.weight3 = app.arm.mass3*g;
            
            % arm.weight1 =  ((2.1484e+03)*0.2)*g
            % % (((2.1484e+03)-((2.1484e+03)*0.2))/2)
            % arm.weight2 = (((2.1484e+03)-((2.1484e+03)*0.2))/2)*g
            % arm.weight3 = (((2.1484e+03)-((2.1484e+03)*0.2))/2)*g
            
            
            % Base linkage of the arm position and orientation
            app.arm.x_position = 1.75;
            app.arm.y_position = app.vehicle.height+app.vehicle.wheelRadius; 
            app.arm.angle = 128*pi/180;
            
            % Second linkage of the arm position and orientation
            app.arm.angle_L2 = 0*pi/180;
            app.arm.x_position2 = app.arm.x_position+app.arm.base*cos(app.arm.angle);
            app.arm.y_position2 = app.arm.y_position+app.arm.base*sin(app.arm.angle);
            
            % End Effector linkage of the arm position and orientation
            app.arm.angle_L3 = 200*pi/180;
            app.arm.x_position3 = app.arm.x_position2+app.arm.secondlink*cos(app.arm.angle_L2);
            app.arm.y_position3 = app.arm.y_position2+app.arm.secondlink*sin(app.arm.angle_L2);
            
            % % Simplified Model of Gripper
            % arm.angle_L4 = 270*pi/180;
            % arm.x_position4 = arm.x_position3+arm.endeffector*sin(arm.angle_L3);
            % arm.y_position4 = arm.y_position3+arm.endeffector*cos(arm.angle_L3)
            
            % Prototype Arm 
            LeWanxArm.base = 101.73/1000; % m 
            LeWanxArm.secondlink = 101.73/1000; % m 
            LeWanxArm.EE = 56.642/1000; % m 
            % LeWanxArm.G = 114.808/1000; % m 
            
            app.scale(1) = LeWanxArm.base/app.arm.base;
            app.scale(2)=LeWanxArm.secondlink/app.arm.secondlink;
            app.scale(3) = LeWanxArm.EE/app.arm.endeffector;
            % scale(4) = LeWanxArm.G/arm.gripper;
            
            scaling(app,app.scale_factor,app.vehicle,app.arm);
            clf;
        end

        % Value changed function: Slider
        function SliderValueChanged(app, event)
            value = app.Slider.Value;
            app.scale_factor = value;
            F = scaling(app,app.scale_factor,vehicle,app.arm);
            imshow(F, 'Parent', app.UIAxes)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.SliderLabel = uilabel(app.UIFigure);
            app.SliderLabel.Text = 'Slider';
            app.Slider = uislider(app.UIFigure);
            app.Slider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Robotic Arm Simulation')
            xlabel(app.UIAxes, 'X (m)')
            ylabel(app.UIAxes, 'Y (m)')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Dynamic_Scaling_GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end